classdef matlabmathappV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        PeriodBKnob                     matlab.ui.control.Knob
        PeriodBKnobLabel                matlab.ui.control.Label
        VerticalShiftDEditField         matlab.ui.control.NumericEditField
        VerticalShiftDEditFieldLabel    matlab.ui.control.Label
        HorizontalShiftCEditField       matlab.ui.control.NumericEditField
        HorizontalShiftCEditFieldLabel  matlab.ui.control.Label
        AmplitudeASlider                matlab.ui.control.Slider
        AmplitudeASliderLabel           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
       

        function plotgraph2(app, a, b, c, d)
            x= 0:0.01:(360/180)*pi;
            y=a*sin(b*(x+c))+d ;
            
            dy = diff(y);
            dx = diff(x);
            dydx = dy./dx;
            
            z=cos(x);
            hold off
            
            plot(app.UIAxes2,y,'r');
            hold on
            
            plot(app.UIAxes2,dydx,'b'); % COMMENT: dydx IS dy./dx SO IT'S diff(y)./diff(x)  (https://www.mathworks.com/help/matlab/ref/diff.html)
            hold on
            
            plot(app.UIAxes2,z,'g');
            % xlabel('x');
            legend('y=sin(x)', 'dy/dx', 'cos(x)');
            grid on
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %plotgraph (app); 
        end

        % Callback function: AmplitudeASlider, AmplitudeASlider, 
        % HorizontalShiftCEditField, PeriodBKnob, PeriodBKnob, 
        % VerticalShiftDEditField
        function PlotButtonPushed(app, event)


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed2(app, event)
            a = app.AmplitudeASlider.Value; 
            b = app.PeriodBKnob.Value; 
            c = app.HorizontalShiftCEditField.Value; 
            d = app.VerticalShiftDEditField.Value; 
            plotgraph2(app,a,b,c,d);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AmplitudeASliderLabel = uilabel(app.LeftPanel);
            app.AmplitudeASliderLabel.Text = 'Amplitude(A)';
            app.AmplitudeASlider = uislider(app.LeftPanel);
            app.AmplitudeASlider.Limits = [0 10];
            app.AmplitudeASlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.AmplitudeASlider.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.HorizontalShiftCEditFieldLabel = uilabel(app.LeftPanel);
            app.HorizontalShiftCEditFieldLabel.Text = 'Horizontal Shift (C)';
            app.HorizontalShiftCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HorizontalShiftCEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.VerticalShiftDEditFieldLabel = uilabel(app.LeftPanel);
            app.VerticalShiftDEditFieldLabel.Text = 'Vertical Shift (D)';
            app.VerticalShiftDEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VerticalShiftDEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PeriodBKnobLabel = uilabel(app.LeftPanel);
            app.PeriodBKnobLabel.Text = 'Period (B)';
            app.PeriodBKnob = uiknob(app.LeftPanel, 'continuous');
            app.PeriodBKnob.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PeriodBKnob.ValueChangingFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed2, true);
            app.PlotButton.Text = 'Plot';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Sine and Cosine Graph')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlabmathappV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end